classdef GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure           matlab.ui.Figure
        ContinueButton     matlab.ui.control.Button
        TeachRobotsButton  matlab.ui.control.Button
        ESTOPButton        matlab.ui.control.Button
        CocaColaButton     matlab.ui.control.Button
        FantaButton        matlab.ui.control.Button
        SoloButton         matlab.ui.control.Button
        LemonadeButton     matlab.ui.control.Button
        Image              matlab.ui.control.Image
    end

    
    properties (Access = public)
        Lemonade = false;
        Solo = false;
        Fanta = false;
        CocaCola = false;
        teach = false;

        EStopPressed = false;       % Variable to store if the estop has been hit or not
        continuePressed = false;    % Second touch to resume program.
       
        Assignment;
    end
   

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.Assignment = Assignment2Group();
        end

        % Button pushed function: LemonadeButton
        function LemonadeButtonPushed(app, event)
            if app.Lemonade == true
                app.Lemonade = false;
            else
                app.Lemonade = true;
            end
            display("Lemonade Selected");
            % Pass drink selection to main
            app.Assignment.CollectOrderFromCustomer("Lemonade")
        end

        % Button pushed function: SoloButton
        function SoloButtonPushed(app, event)
            if app.Solo == true
                app.Solo = false;
            else
                app.Solo = true;
            end
            display("Solo Selected");
            % Pass drink selection to main          
            app.Assignment.CollectOrderFromCustomer("Solo")
        end

        % Button pushed function: FantaButton
        function FantaButtonPushed(app, event)
            if app.Fanta == true
                app.Fanta = false;
            else
                app.Fanta = true;
            end            
            display("Fanta Selected");
            % Pass drink selection to main
            app.Assignment.CollectOrderFromCustomer("Fanta")
        end

        % Button pushed function: CocaColaButton
        function CocaColaButtonPushed(app, event)
            if app.CocaCola == true
                app.CocaCola = false;
            else
                app.CocaCola = true;
            end
            display("CocaCola Selected");
            % Pass drink selection to main
            app.Assignment.CollectOrderFromCustomer("CocaCola")
        end

        % Button pushed function: ESTOPButton
        function ESTOPButtonPushed(app, event)
            app.EStopPressed = ~app.EStopPressed;

            % if e-stop has been pressed on, start/continue must turn false
            if app.EStopPressed
                % Setting continue button to false (for two step starting)
                app.continuePressed = false;
                display("EStop Engaged");
            else
                % Continue button can be pressed now (e-stop off)
                display("Press Continue when Ready");
            end
        end

        % Button pushed function: TeachRobotsButton
        function TeachRobotsButtonPushed(app, event)
            if app.teach == true
                app.teach = false;
            else
                app.teach = true;
            end
            display("Teach mode engaged");

            %Initiate teach GUI
            teachGUI();
        end

        % Button pushed function: ContinueButton
        function ContinueButtonPushed(app, event)
            % Checking if the e-stop is off (meaning robot can start/continue)
            if ~app.EStopPressed
                % Toggling continuePressed property to opposite state
                app.continuePressed = true;
                display("Robot Disengaged. Function Continued");
            else
                % Logging that e-stop is on and therefore robots cannot operate
                display("EStop Engaged");
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'Background.png');
            app.LemonadeButton = uibutton(app.UIFigure, 'push');
            app.LemonadeButton.ButtonPushedFcn = createCallbackFcn(app, @LemonadeButtonPushed, true);
            app.LemonadeButton.Text = 'Lemonade';
            app.SoloButton = uibutton(app.UIFigure, 'push');
            app.SoloButton.ButtonPushedFcn = createCallbackFcn(app, @SoloButtonPushed, true);
            app.SoloButton.Text = 'Solo';
            app.FantaButton = uibutton(app.UIFigure, 'push');
            app.FantaButton.ButtonPushedFcn = createCallbackFcn(app, @FantaButtonPushed, true);
            app.FantaButton.Text = 'Fanta';
            app.CocaColaButton = uibutton(app.UIFigure, 'push');
            app.CocaColaButton.ButtonPushedFcn = createCallbackFcn(app, @CocaColaButtonPushed, true);
            app.CocaColaButton.Text = 'Coca Cola';
            app.ESTOPButton = uibutton(app.UIFigure, 'push');
            app.ESTOPButton.ButtonPushedFcn = createCallbackFcn(app, @ESTOPButtonPushed, true);
            app.ESTOPButton.Text = 'E-STOP';
            app.TeachRobotsButton = uibutton(app.UIFigure, 'push');
            app.TeachRobotsButton.ButtonPushedFcn = createCallbackFcn(app, @TeachRobotsButtonPushed, true);
            app.TeachRobotsButton.Text = 'Teach Robots';
            app.ContinueButton = uibutton(app.UIFigure, 'push');
            app.ContinueButton.ButtonPushedFcn = createCallbackFcn(app, @ContinueButtonPushed, true);
            app.ContinueButton.Text = 'Continue';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end